classdef ModulacionAM1vozex < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxesmoduladora    matlab.ui.control.UIAxes
        Image               matlab.ui.control.Image
        IngenieraenTelecomunicacionesModulacionAMLabel  matlab.ui.control.Label
        FCHzSpinnerLabel    matlab.ui.control.Label
        FCHzSpinner         matlab.ui.control.Spinner
        FMHzSpinnerLabel    matlab.ui.control.Label
        FMHzSpinner         matlab.ui.control.Spinner
        VCSpinnerLabel      matlab.ui.control.Label
        VCSpinner           matlab.ui.control.Spinner
        VMSpinnerLabel      matlab.ui.control.Label
        VMSpinner           matlab.ui.control.Spinner
        MLabel              matlab.ui.control.Label
        MSpinner            matlab.ui.control.Spinner
        BwHzSpinnerLabel    matlab.ui.control.Label
        BwHzSpinner         matlab.ui.control.Spinner
        SeleccionelosparametrosqueconozcaPanel  matlab.ui.container.Panel
        FMCheckBox          matlab.ui.control.CheckBox
        VCCheckBox          matlab.ui.control.CheckBox
        VMCheckBox          matlab.ui.control.CheckBox
        BwCheckBox          matlab.ui.control.CheckBox
        MdeModCheckBox      matlab.ui.control.CheckBox
        UIAxesportadora     matlab.ui.control.UIAxes
        UIAxesmodulada      matlab.ui.control.UIAxes
        GraficarButton      matlab.ui.control.Button
        UIAxesespectro      matlab.ui.control.UIAxes
        AutoEscalaCheckBox  matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        
        function calculos(app)
            checkfc = 1;
            checkfm = app.FMCheckBox.Value;
            checkBw = app.BwCheckBox.Value;
            checkvc = app.VCCheckBox.Value;
            checkvm = app.VMCheckBox.Value;
            checkem = app.MdeModCheckBox.Value;
            fc=app.FCHzSpinner.Value;
            fm=app.FMHzSpinner.Value    ;    
            vm=app.VMSpinner.Value;
            vc=app.VCSpinner.Value;
            m=app.MSpinner.Value/100;
            Bw=app.BwHzSpinner.Value;
            if checkfc == 1 && checkBw == 1
                fm=Bw/2;
                app.FMHzSpinnerLabel.Text = join(['FM = ',num2str(fm)],'Hz')
            elseif checkfc == 1 && checkfm == 1
                Bw=2*fm;     
                app.BwHzSpinnerLabel.Text = join(['Bw = ',num2str(Bw),'Hz'])
            elseif checkfc == 0 | checkfm == 0 | checkBw == 0
                
            end
            if checkvc == 1 && checkem == 1
                vm=m*vc;
                app.VMSpinnerLabel.Text = join(['VM = ',num2str(vm),'v'])
            elseif checkvc == 1 && checkvm == 1
                m=(vm/vc)*100;
                app.MLabel.Text = join(['M = ',num2str(m),'%'])
            elseif checkvm == 1 && checkem == 1
                vc=vm/m;
                app.VCSpinnerLabel.Text = join(['VC = ',num2str(vc),'v'])
            elseif checkvc == 0 | checkvm == 0 | checkm == 0
                
            end
            Fs = 100000;            % Sampling frequency
            T = 1/Fs;             % Sampling period
            L = 100000;             % Length of signal
            t = (0:L-1)*T;        % Time vector
%             vc=4
%             fc=100000
%             vm=3
%             fm=3000
            portadora=vc*sin(2*pi*fc*t)
            mensaje=vm*sin(2*pi*t*fm)
            bli=vm/2*cos(2*pi*t*(fc-fm))
            bls=vm/2*cos(2*pi*t*(fc+fm))
            A=vc+vm*sin(2*pi*t*fm)
            vmod = vc*sin(2*pi*fc*t)+vm/2*cos(2*pi*t*(fc-fm))-vm/2*cos(2*pi*t*(fc+fm)) ;
            plot(app.UIAxesmoduladora,t,mensaje)   
            
            plot(app.UIAxesportadora,t,portadora)
            plot(app.UIAxesmodulada,t,vmod,t,A,': r',t,-A,': r' )
%             title('Signal Corrupted with Zero-Mean Random Noise')
%             xlabel('t (milliseconds)')
%             ylabel('X(t)')
            Y = fft(vmod);
            % L=100
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            
            plot(app.UIAxesespectro,f,P1)
            %             title('Single-Sided Amplitude Spectrum of X(t)')
            %             xlabel('f (Hz)')
            %             ylabel('|P1(f)|')
            if app.AutoEscalaCheckBox.Value == 1
                app.UIAxesmoduladora.XLim = [0,(1/fm)*10]
                app.UIAxesmodulada.XLim = [0,(1/fm)*10]
                app.UIAxesportadora.XLim = [0,(1/fm)*10]
                app.AutoEscalaCheckBox.Value = 0
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BwCheckBox, FMCheckBox
        function FCCheckBoxValueChanged(app, event)
           
            valuefc = 1;
            valuefm = app.FMCheckBox.Value;
            valueBw = app.BwCheckBox.Value;
           if valuefc == 1 && valueBw == 1
                app.FMCheckBox.Enable = 'off';
                app.FCHzSpinner.Visible = 'on';
                app.BwHzSpinner.Visible = 'on';
          
           elseif valuefc == 1 && valuefm == 1
                app.BwCheckBox.Enable = 'off';
                app.FMHzSpinner.Visible = 'on'; 
                app.FCHzSpinner.Visible = 'on'; 
           elseif valuefm == 1 && valueBw == 1

                app.FMHzSpinner.Visible = 'on'; 
                app.BwHzSpinner.Visible = 'on';
           elseif valuefc == 0 | valuefm == 0 | valueBw == 0
                app.FMCheckBox.Enable = 'on';
                app.FMHzSpinner.Visible = 'off';  
                app.BwCheckBox.Enable = 'on';
                app.BwHzSpinner.Visible = 'off';

                app.FCHzSpinner.Visible = 'off';
           end
           
        end

        % Value changed function: MdeModCheckBox, VCCheckBox, 
        % VMCheckBox
        function VCCheckBoxValueChanged(app, event)
            valuevc = app.VCCheckBox.Value;
            valuevm = app.VMCheckBox.Value;
            valuem = app.MdeModCheckBox.Value;
           if valuevc == 1 && valuem == 1
                app.VMCheckBox.Enable = 'off';
                app.VCSpinner.Visible = 'on'; 
                app.MSpinner.Visible = 'on';
           elseif valuevc == 1 && valuevm == 1
                app.MdeModCheckBox.Enable = 'off';
                app.VCSpinner.Visible = 'on';
                app.VMSpinner.Visible = 'on';
           elseif valuevm == 1 && valuem == 1
                app.VCCheckBox.Enable = 'off';
                app.MSpinner.Visible = 'on';
                app.VMSpinner.Visible = 'on';
           elseif valuevc == 0 | valuevm == 0 | valuem == 0
                app.VMCheckBox.Enable = 'on';
                app.VMSpinner.Visible = 'off';   
                app.MdeModCheckBox.Enable = 'on';
                app.MSpinner.Visible = 'off'
                app.VCCheckBox.Enable = 'on';
                app.VCSpinner.Visible = 'off';
           end
            
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            calculos(app)
            app.AutoEscalaCheckBox.Visible= 'on';
        end

        % Value changed function: AutoEscalaCheckBox
        function AutoEscalaCheckBoxValueChanged(app, event)
            value = app.AutoEscalaCheckBox.Value; 
            calculos(app)
            app.AutoEscalaCheckBox.Visible= 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesmoduladora = uiaxes(app.UIFigure);
            title(app.UIAxesmoduladora, 'MODULADORA')
            xlabel(app.UIAxesmoduladora, 'Tiempo (segundo)')
            ylabel(app.UIAxesmoduladora, 'Y')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'escudo_oscuro.png';
            app.IngenieraenTelecomunicacionesModulacionAMLabel = uilabel(app.UIFigure);
            app.IngenieraenTelecomunicacionesModulacionAMLabel.Text = {'Ingeniería en Telecomunicaciones'; 'Modulacion AM'};
            app.FCHzSpinnerLabel = uilabel(app.UIFigure);
            app.FCHzSpinnerLabel.Text = 'FC(Hz)';
            app.FCHzSpinner = uispinner(app.UIFigure);
            app.FCHzSpinner.Limits = [0 50000];
            app.FCHzSpinner.Visible = 'off';
            app.FMHzSpinnerLabel = uilabel(app.UIFigure);
            app.FMHzSpinnerLabel.Text = 'FM(Hz)';
            app.FMHzSpinner = uispinner(app.UIFigure);
            app.FMHzSpinner.Limits = [0 Inf];
            app.FMHzSpinner.Visible = 'off';
            app.VCSpinnerLabel = uilabel(app.UIFigure);
            app.VCSpinnerLabel.Text = 'VC';
            app.VCSpinner = uispinner(app.UIFigure);
            app.VCSpinner.Limits = [0 Inf];
            app.VCSpinner.Visible = 'off';
            app.VMSpinnerLabel = uilabel(app.UIFigure);
            app.VMSpinnerLabel.Text = 'VM';
            app.VMSpinner = uispinner(app.UIFigure);
            app.VMSpinner.Limits = [0 Inf];
            app.VMSpinner.Visible = 'off';
            app.MLabel = uilabel(app.UIFigure);
            app.MLabel.Text = 'M(%)';
            app.MSpinner = uispinner(app.UIFigure);
            app.MSpinner.Limits = [0 100];
            app.MSpinner.Visible = 'off';
            app.BwHzSpinnerLabel = uilabel(app.UIFigure);
            app.BwHzSpinnerLabel.Text = 'Bw(Hz)';
            app.BwHzSpinner = uispinner(app.UIFigure);
            app.BwHzSpinner.Limits = [0 Inf];
            app.BwHzSpinner.Visible = 'off';
            app.SeleccionelosparametrosqueconozcaPanel = uipanel(app.UIFigure);
            app.SeleccionelosparametrosqueconozcaPanel.Title = 'Seleccione los parametros que conozca';
            app.FMCheckBox = uicheckbox(app.SeleccionelosparametrosqueconozcaPanel);
            app.FMCheckBox.ValueChangedFcn = createCallbackFcn(app, @FCCheckBoxValueChanged, true);
            app.FMCheckBox.Text = 'FM';
            app.VCCheckBox = uicheckbox(app.SeleccionelosparametrosqueconozcaPanel);
            app.VCCheckBox.ValueChangedFcn = createCallbackFcn(app, @VCCheckBoxValueChanged, true);
            app.VCCheckBox.Text = 'VC';
            app.VMCheckBox = uicheckbox(app.SeleccionelosparametrosqueconozcaPanel);
            app.VMCheckBox.ValueChangedFcn = createCallbackFcn(app, @VCCheckBoxValueChanged, true);
            app.VMCheckBox.Text = 'VM';
            app.BwCheckBox = uicheckbox(app.SeleccionelosparametrosqueconozcaPanel);
            app.BwCheckBox.ValueChangedFcn = createCallbackFcn(app, @FCCheckBoxValueChanged, true);
            app.BwCheckBox.Text = 'Bw';
            app.MdeModCheckBox = uicheckbox(app.SeleccionelosparametrosqueconozcaPanel);
            app.MdeModCheckBox.ValueChangedFcn = createCallbackFcn(app, @VCCheckBoxValueChanged, true);
            app.MdeModCheckBox.Text = 'M % de Mod';
            app.UIAxesportadora = uiaxes(app.UIFigure);
            title(app.UIAxesportadora, 'PORTADORA')
            xlabel(app.UIAxesportadora, 'Tiempo (segundo)')
            ylabel(app.UIAxesportadora, 'Y')
            app.UIAxesmodulada = uiaxes(app.UIFigure);
            title(app.UIAxesmodulada, 'MODULADA')
            xlabel(app.UIAxesmodulada, 'Tiempo (segundo)')
            ylabel(app.UIAxesmodulada, 'Y')
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.UIAxesespectro = uiaxes(app.UIFigure);
            title(app.UIAxesespectro, 'ESPECTRO')
            xlabel(app.UIAxesespectro, 'Frecuencia(Hz)')
            ylabel(app.UIAxesespectro, 'Y')
            app.AutoEscalaCheckBox = uicheckbox(app.UIFigure);
            app.AutoEscalaCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoEscalaCheckBoxValueChanged, true);
            app.AutoEscalaCheckBox.Visible = 'off';
            app.AutoEscalaCheckBox.Text = 'Auto Escala';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModulacionAM1vozex

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end